classdef OpStapMetSuperKracht2025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LengteMenu                      matlab.ui.container.Menu
        KrachtMenu                      matlab.ui.container.Menu
        ZwaartekrachtMenu               matlab.ui.container.Menu
        Creer_jezelf_als_modelMenu      matlab.ui.container.Menu
        SpierkrachtLabelStrongWoman     matlab.ui.control.Label
        Image2_4                        matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ZwaartekrachtLabelEigenModel    matlab.ui.control.Label
        SpierkrachtLabelEigenModel      matlab.ui.control.Label
        cmLabelEigenModel               matlab.ui.control.Label
        LengteLabelEigenModel           matlab.ui.control.Label
        LengteSliderEigenModel          matlab.ui.control.RangeSlider
        OpstapmetsuperkrachtWhite       matlab.ui.control.Label
        gLabelEigenModel                matlab.ui.control.Label
        ZwaartekrachtEigenModel         matlab.ui.control.NumericEditField
        PlanetXblack                    matlab.ui.control.Image
        NaamModelEditField              matlab.ui.control.EditField
        SluitvideoButton                matlab.ui.control.Button
        SpeelvideoButton                matlab.ui.control.Button
        StartsimulatieButton            matlab.ui.control.Button
        GroepEditField                  matlab.ui.control.EditField
        MassaEditFieldEigenModel        matlab.ui.control.NumericEditField
        MassaEditFieldLabel             matlab.ui.control.Label
        kgLabelEigenModel               matlab.ui.control.Label
        SpierkrachtEigenModel           matlab.ui.control.NumericEditField
        procentlabelEigenModel          matlab.ui.control.Label
        maan                            matlab.ui.control.Image
        GIFsmurf                        matlab.ui.control.Image
        DENKsmurf                       matlab.ui.control.Image
        GIFreus                         matlab.ui.control.Image
        DENKreus                        matlab.ui.control.Image
        smurf                           matlab.ui.control.Image
        giant                           matlab.ui.control.Image
        StrongWoman                     matlab.ui.control.Image
        ZwaartekrachtEditFieldMaan      matlab.ui.control.NumericEditField
        ZwaartekrachtEditFieldPlanetX   matlab.ui.control.NumericEditField
        ZwaartekrachtEditFieldLabelPlanetX  matlab.ui.control.Label
        gLabel_3                        matlab.ui.control.Label
        ZwaartekrachtEditFieldLabelMaan  matlab.ui.control.Label
        MaanLabel                       matlab.ui.control.Label
        gLabel                          matlab.ui.control.Label
        PlaneetXLabel                   matlab.ui.control.Label
        LengteSliderReusSmurf           matlab.ui.control.RangeSlider
        procentSpierkracht              matlab.ui.control.Label
        SpierkrachtEditField            matlab.ui.control.NumericEditField
        Spaceman                        matlab.ui.control.Image
        PlaneetX                        matlab.ui.control.Image
        OpstapmetsuperkrachtBlue        matlab.ui.control.Label
        GroepEditFieldLabel             matlab.ui.control.Label
        BackgroundImageSmurfReusKracht  matlab.ui.control.Image
        spaceBackground                 matlab.ui.control.Image
        Background_creerjeeigenmodel    matlab.ui.control.Image
    end

    
    properties (Access = public)
        % Layout
        ink_colour = [139,69,19]/256; % RGB code for "ink" i.e. text and figures
        paper_colour = [235,222,173]/256; % RGB code for "paper" i.e. background

        % Default values
        default_height = 1.8; % [m]
        default_ratio_fingertip_elbow = 1/4;
        default_ratio_elbow_shoulder = 1/8;
        default_ratio_shoulder_width = 1/4;
        default_ratio_hip_knee = 1/4;
        default_ratio_knee_ground = 1/4;
        default_ratio_foot_length = 1/6;

        % Allocate user input values
        usr_height = 1.8;
        usr_fingertip_elbow = 1/4*1.8;
        usr_elbow_shoulder = 1/8*1.8;
        usr_shoulder_width = 1/4*1.8;
        usr_hip_knee = 1/4*1.8;
        usr_knee_ground = 1/4*1.8;
        usr_foot_length = 1/6*1.8;

        usr_speed = 1.38;

        % scale factors
        scale_factors

        % storage
        path_repo
        path_results
        path_savefolder
        path_geom
        path_casadi
        ModelName
        GroupName
        tbldata = cell(1,4);
        idx_sel_data = 1;
        sel_mot_file
        sel_osim_file

    end
    
    methods (Access = private)

        % Menu selected function: Lengte
        function LengteMenuSelected(app,event)
            app.BackgroundImageSmurfReusKracht.Visible = 'on';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            app.OpstapmetsuperkrachtWhite.Visible = 'off';

            % related to smurf reus
            app.giant.Visible = 'on';
            app.smurf.Visible = 'on';
            app.LengteSliderReusSmurf.Visible = 'on';

            % related to kracht 
            app.StrongWoman.Visible = 'off';
            app.procentSpierkracht.Visible = 'off';
            app.SpierkrachtEditField.Visible = 'off';
            app.SpierkrachtLabelStrongWoman.Visible  = 'off';

            % space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.PlaneetXLabel.Visible = 'off';
            app.gLabel.Visible = 'off';
            app.ZwaartekrachtEditFieldMaan.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelMaan.Visible = 'off';
            app.gLabel_3.Visible = 'off';
            app.MaanLabel.Visible = 'off';
           app.ZwaartekrachtEditFieldPlanetX.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelPlanetX.Visible = 'off';


             app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';

             app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';            
        end


        % Menu selected function: Kracht
        function KrachtMenuSelected(app,event)
            app.BackgroundImageSmurfReusKracht.Visible = 'on';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            app.OpstapmetsuperkrachtWhite.Visible = 'off';

            % related to kracht 
            app.StrongWoman.Visible = 'on';
            app.procentSpierkracht.Visible = 'on';
            app.SpierkrachtEditField.Visible = 'on';
            app.SpierkrachtLabelStrongWoman.Visible  = 'on';

            % related to smurf reus
            app.giant.Visible = 'off';
            app.smurf.Visible = 'off';
            app.LengteSliderReusSmurf.Visible = 'off';


            % space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.PlaneetXLabel.Visible = 'off';
            app.gLabel.Visible = 'off';
            app.ZwaartekrachtEditFieldMaan.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelMaan.Visible = 'off';
            app.gLabel_3.Visible = 'off';
            app.MaanLabel.Visible = 'off';
           app.ZwaartekrachtEditFieldPlanetX.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelPlanetX.Visible = 'off';


            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';

             app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';            

        end


        % Menu selected function: ZwaarteKracht
        function ZwaartekrachtMenuSelectedFix(app,event)

            % related to smurf reus kracht
            app.BackgroundImageSmurfReusKracht.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'off';

            % related to kracht 
            app.StrongWoman.Visible = 'off';
            app.procentSpierkracht.Visible = 'off';
            app.SpierkrachtEditField.Visible = 'off';
            app.SpierkrachtLabelStrongWoman.Visible  = 'off';

            % related to smurf reus
            app.giant.Visible = 'off';
            app.smurf.Visible = 'off';
            app.LengteSliderReusSmurf.Visible = 'off';

            % space
            app.OpstapmetsuperkrachtWhite.Visible = 'on';
            app.Spaceman.Visible = 'on';
            app.spaceBackground.Visible = 'on';
            app.maan.Visible = 'on';
            app.PlaneetX.Visible = 'on';
            app.PlaneetXLabel.Visible = 'on';
            app.gLabel.Visible = 'on';
            app.ZwaartekrachtEditFieldMaan.Visible = 'on';
            app.ZwaartekrachtEditFieldLabelMaan.Visible = 'on';
            app.gLabel_3.Visible = 'on';
            app.MaanLabel.Visible = 'on';
           app.ZwaartekrachtEditFieldPlanetX.Visible = 'on';
            app.ZwaartekrachtEditFieldLabelPlanetX.Visible = 'on';

            app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';

             app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';

        end


        function CreerjezelfalsmodelMenuSelected(app,event)
            % related to smurf reus kracht
            app.BackgroundImageSmurfReusKracht.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'off';

            % related to kracht 
            app.StrongWoman.Visible = 'off';
            app.procentSpierkracht.Visible = 'off';
            app.SpierkrachtEditField.Visible = 'off';
            app.SpierkrachtLabelStrongWoman.Visible  = 'off';

            % related to smurf reus
            app.giant.Visible = 'off';
            app.smurf.Visible = 'off';
            app.LengteSliderReusSmurf.Visible = 'off';


            % space eigen model
            app.OpstapmetsuperkrachtWhite.Visible = 'on';
            
            % space
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.PlaneetXLabel.Visible = 'off';
            app.gLabel.Visible = 'off';
            app.ZwaartekrachtEditFieldMaan.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelMaan.Visible = 'off';
            app.gLabel_3.Visible = 'off';
            app.MaanLabel.Visible = 'off';
           app.ZwaartekrachtEditFieldPlanetX.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelPlanetX.Visible = 'off';

            app.cmLabelEigenModel.Visible = 'on';
            app.LengteLabelEigenModel.Visible = 'on';
            app.LengteSliderEigenModel.Visible = 'on';
            app.gLabelEigenModel.Visible = 'on';
            app.PlanetXblack.Visible = 'on';
            app.NaamModelEditField.Visible = 'on';
            app.MassaEditFieldEigenModel.Visible = 'on';
            app.MassaEditFieldLabel.Visible = 'on';
            app.kgLabelEigenModel.Visible = 'on';
            app.procentlabelEigenModel.Visible = 'on';
            app.SpierkrachtEigenModel.Visible = 'on';
            app.Background_creerjeeigenmodel.Visible = 'on';
            app.ZwaartekrachtEigenModel.Visible = 'on';
            app.ZwaartekrachtLabelEigenModel.Visible = 'on';
            app.SpierkrachtLabelEigenModel.Visible = 'on';


            % eigen model

        end


% 
%         % user inputs with default values
%         function [] = updateUserInput(app)
% %             app.usr_height = app.default_height;
%             app.usr_fingertip_elbow = app.default_ratio_fingertip_elbow*app.usr_height;
%             app.usr_elbow_shoulder = app.default_ratio_elbow_shoulder*app.usr_height;
%             app.usr_shoulder_width = app.default_ratio_shoulder_width*app.usr_height;
%             app.usr_hip_knee = app.default_ratio_hip_knee*app.usr_height;
%             app.usr_knee_ground = app.default_ratio_knee_ground*app.usr_height;
%             app.usr_foot_length = app.default_ratio_foot_length*app.usr_height;
%         end
% 
%         % read user inputs
%         function [] = readUserInput(app)
%             % app.usr_height = app.LichaamslengteEditField.Value;
%             app.usr_fingertip_elbow = app.AfstandelleboogtotvingertopEditField.Value;
%             app.usr_elbow_shoulder = app.AfstandschoudertotelleboogEditField.Value;
%             app.usr_shoulder_width = app.AfstandtussenschoudersEditField.Value;
%             app.usr_hip_knee = app.AfstandheuptotknieEditField.Value;
%             app.usr_knee_ground = app.AfstandknietotgrondEditField.Value;
%             app.usr_foot_length = app.LengtevanvoetEditField.Value;
%         end
% 
%         % set default user inputs
%         function [] = writeDefaultUserInput(app)
%             app.AfstandelleboogtotvingertopEditField.Value = round(app.usr_fingertip_elbow,1);
%             app.AfstandschoudertotelleboogEditField.Value = round(app.usr_elbow_shoulder,1);
%             app.AfstandtussenschoudersEditField.Value = round(app.usr_shoulder_width,1);
%             app.AfstandheuptotknieEditField.Value = round(app.usr_hip_knee,1);
%             app.AfstandknietotgrondEditField.Value = round(app.usr_knee_ground,1);
%             app.LengtevanvoetEditField.Value = round(app.usr_foot_length,1);
%         end
        
        % % set limits on input
        % function updateInputLimits(app)
        %     % BMI between 10 and 50
        %     mlim = round([10,50]*((app.usr_height/100).^2));
        %     m0 = app.MassaEditFieldEigenModel.Value;
        %     m0 = round(min([m0,mlim(2)]));
        %     m0 = round(max([m0,mlim(1)]));
        %     app.MassaEditFieldEigenModel.Limits = [0,3000];
        %     app.MassaEditFieldEigenModel.Value = m0;
        %     app.MassaEditFieldEigenModel.Limits = mlim;
        % 
        % 
        %     app.AfstandelleboogtotvingertopEditField.Limits(2) = app.usr_height/2;
        %     app.AfstandschoudertotelleboogEditField.Limits(2) = app.usr_height/2;
        %     app.AfstandtussenschoudersEditField.Limits(2) = app.usr_height/2;
        %     app.AfstandheuptotknieEditField.Limits(2) = app.usr_height/2;
        %     app.AfstandknietotgrondEditField.Limits(2) = app.usr_height/2;
        %     app.LengtevanvoetEditField.Limits(2) = app.usr_height/2;
        % 
        % end

        % % calls %updateDrawing
        % function %updateDrawing(app)
        %     % read user inputs
        %     readUserInput(app)
        %     % call function to update drawing
        %     app.scale_factors = %updateDrawing(app.usr_height,app.usr_fingertip_elbow,app.usr_elbow_shoulder,...
        %     app.usr_shoulder_width,app.usr_hip_knee,app.usr_knee_ground,app.usr_foot_length,...
        %     app.UIAxes,app.ink_colour,app.paper_colour);
        % end
        
%         % load results and put them in table
%         function loadResultsTable(app)
%             % search folder
%             MatFiles = dir(fullfile(app.path_savefolder,app.GroupName,'*.mat'));
%             % Empty display table if folder is empty
%             if isempty(MatFiles)
%                 app.NaamAfstandSnelheidListBox.Items = {};
%                 app.NaamAfstandSnelheidListBox.ItemsData = {};
%                 return
%             end
%             % loop over files
%             for i=1:length(MatFiles)
%                 % calc distance walked for given energy budget
%                 [distance_i,avg_v_i] = calcDistance(app,char(fullfile(MatFiles(i).folder,MatFiles(i).name)));
%                 name_tmp = MatFiles(i).name(1:end-4);
%                 name_tmp2 = getName(app,name_tmp);
% 
%                 % add name, distance, mot file to array
%                 app.tbldata{i,1} = [name_tmp2 ': ' num2str(distance_i*1e-3,'%.1f') ' km, aan ' num2str(avg_v_i) ' km/h'];
%                 app.tbldata{i,2} = fullfile(MatFiles(i).folder,[name_tmp '.mot']);
%                 app.tbldata{i,3} = distance_i;
%                 app.tbldata{i,3} = avg_v_i;
% 
%             end
% 
%             if ~isempty(app.tbldata{1,1})
%                 app.NaamAfstandSnelheidListBox.Items = app.tbldata(:,1);
%                 app.NaamAfstandSnelheidListBox.ItemsData = app.tbldata(:,2);
%             end
% 
% %             % command for running .py file
% %             dist = num2str(distance_i*1e-3);
% %             vel = num2str(avg_v_i);
% %             llen       = num2str(app.LichaamslengteEditField.Value);
% %             mass       = num2str(app.MassaEditFieldEigenModel.Value);
% %             shoulders  = num2str(app.AfstandtussenschoudersEditField.Value);
% %             arm_upper  = num2str(app.AfstandschoudertotelleboogEditField.Value);
% %             arm_lower  = num2str(app.AfstandelleboogtotvingertopEditField.Value);
% %             leg_upper  = num2str(app.AfstandheuptotknieEditField.Value);
% %             leg_lower  = num2str(app.AfstandknietotgrondEditField.Value);
% %             foot       = num2str(app.LengtevanvoetEditField.Value);
% %             strength   = num2str(app.SpierkrachtEditField.Value);
% %             cmd = ['autoFill.py ' app.GroepEditField.Value ' ' app.NaamModelEditField.Value ...
% %                 ' ' dist ' ' vel ' ' llen ' ' mass ' ' shoulders ' ' arm_upper...
% %                 ' ' arm_lower ' ' leg_upper ' ' leg_lower ' ' foot ' ' strength];
% %             pyrunfile(cmd);
% 
        
        
        function [total_distance, avg_vel] = calcDistance(app,savepath)
            %% get distance
            E_metab = 778e3; % 778 kJ = 1 pancake
            load(savepath,'R');
            COT = R.metabolics.Bhargava2004.COT;
            total_distance = round(E_metab/(COT*R.misc.body_mass));
            avg_vel = round(R.kinematics.avg_velocity*3.6);
            clear('R');

        end

        function name_tmp2 = getName(app,name_tmp)
            if strcmp(name_tmp(end-2:end-1),'_v')
                name_tmp2 = name_tmp(1:end-3);
            elseif strcmp(name_tmp(end-3:end-2),'_v')
                name_tmp2 = name_tmp(1:end-4);
            else
                name_tmp2 = name_tmp;
            end
        end

        
        function setPaths(app)
%             name = getenv('COMPUTERNAME');
% 
%             % fill these in based on your computer
%             if strcmp(name,'GBW-L-W2122')
%                 init_path_savefolder = 'C:\Users\u0150099\OneDrive - KU Leuven\Resultaten_KinderUniversiteit';
%                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.3\Geometry';
%                 init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
%             elseif strcmp(name, 'GBW-L-W2075')
%                 init_path_savefolder = 'C:\Users\u0138016\OneDrive - KU Leuven\Outreach\Kinderuniversiteit\2022\Resultaten';
%                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.3\Geometry';
%                 init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
%             else
%                 init_path_savefolder = 'C:\Users\u0138016\OneDrive - KU Leuven\Outreach\Kinderuniversiteit\2022\Resultaten';
%                 init_path_geom = 'C:\GBW_MyPrograms\OpenSim 4.3\Geometry';
%                 init_path_casadi = 'C:\GBW_MyPrograms\casadi_3_5_5';
%             end


            [init_path_savefolder,init_path_geom,init_path_casadi] = getPaths();
            % test given paths
            if ~exist(init_path_savefolder,'dir')
                error('Please set "init_path_savefolder"')
            end
            if ~exist(init_path_geom,'dir')
                error('Please set "init_path_geom"')
            end
            if ~exist(init_path_casadi,'dir')
                error('Please set "init_path_casadi"')
            end

            % set properties
            app.path_savefolder = init_path_savefolder;
            app.path_geom = init_path_geom;
            app.path_casadi = init_path_casadi;

        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % make default drawing
            % %updateDrawing(app);

            % path repo
            [pathApp,~,~] = fileparts(mfilename('fullpath'));
            [pathRepo,~,~] = fileparts(pathApp);
            app.path_repo = pathRepo;

            addpath([pathRepo '\VariousFunctions'])
            addpath([pathRepo '\App'])

            app.sel_mot_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.mot');

            app.sel_osim_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.osim');

            setPaths(app)

            % updateInputLimits(app)

            % menu
           app.LengteMenu.MenuSelectedFcn = @(src, event) LengteMenuSelected(app,event);
           app.KrachtMenu.MenuSelectedFcn = @(src, event) KrachtMenuSelected(app,event);
           app.ZwaartekrachtMenu.MenuSelectedFcn = @(src,event) ZwaartekrachtMenuSelectedFix(app,event); 
           app.Creer_jezelf_als_modelMenu.MenuSelectedFcn =  @(src,event) CreerjezelfalsmodelMenuSelected(app,event); 

           app.giant.Visible = 'on';
           app.smurf.Visible = 'on';
           app.StrongWoman.Visible = 'on';
          app.BackgroundImageSmurfReusKracht.Visible = 'on';
            
          
          app.StrongWoman.Visible = 'off';
            app.procentSpierkracht.Visible = 'off';
            app.SpierkrachtEditField.Visible = 'off';
            app.SpierkrachtLabelStrongWoman.Visible  = 'off';
              
         
           app.OpstapmetsuperkrachtWhite.Visible = 'off';
            app.Spaceman.Visible = 'off';
            app.spaceBackground.Visible = 'off';
            app.maan.Visible = 'off';
            app.PlaneetX.Visible = 'off';
            app.PlaneetXLabel.Visible = 'off';
            app.gLabel.Visible = 'off';
            app.ZwaartekrachtEditFieldMaan.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelMaan.Visible  = 'off';
            app.gLabel_3.Visible = 'off';
            app.MaanLabel.Visible = 'off';
           app.ZwaartekrachtEditFieldPlanetX.Visible = 'off';
            app.ZwaartekrachtEditFieldLabelPlanetX.Visible = 'off';


             app.cmLabelEigenModel.Visible = 'off';
            app.LengteLabelEigenModel.Visible = 'off';
            app.LengteSliderEigenModel.Visible = 'off';
            app.gLabelEigenModel.Visible = 'off';
            app.PlanetXblack.Visible = 'off';
            app.NaamModelEditField.Visible = 'off';
            app.MassaEditFieldEigenModel.Visible = 'off';
            app.MassaEditFieldLabel.Visible = 'off';
            app.kgLabelEigenModel.Visible = 'off';
            app.procentlabelEigenModel.Visible = 'off';
            app.SpierkrachtEigenModel.Visible = 'off';
            app.Background_creerjeeigenmodel.Visible = 'off';
            app.ZwaartekrachtEigenModel.Visible = 'off';
            app.ZwaartekrachtLabelEigenModel.Visible = 'off';
            app.SpierkrachtLabelEigenModel.Visible = 'off';
            app.OpstapmetsuperkrachtBlue.Visible = 'on';
            

        end

        % Callback function
        function MaaktekeningButtonPushed(app, event)
           %updateDrawing(app);

        end

        % Button pushed function: StartsimulatieButton
        function StartsimulatieButtonPushed(app, event)
            % update drawing and get scale factors
            %updateDrawing(app);

            % if isempty(app.ModelName)
            %     warning('Geef een naam.')
            %     return
            % end
            if isempty(app.GroepEditField)
                warning('Kies een groepsnaam.')
                return
            end

            app.Image.Enable = 'on';
            app.Image.Visible = 'on';
            app.Image2.Enable = 'on';
            app.Image2.Visible = 'on';

            U.savefolder = app.path_savefolder;
            % U.ModelName = app.ModelName;
            U.GroupName = app.GroupName;
            % U.Height = app.usr_height;
            % U.Mass = app.MassaEditFieldEigenModel.Value;
            % U.Force_sf = app.SpierkrachtEditField.Value/100;
            % U.Speed = app.usr_speed;
            U.PathCasadi = app.path_casadi;


            % start simulation
            resultpath = PredSim_wrapper_for_app(U,app.scale_factors);

            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image2.Enable = 'off';
            app.Image2.Visible = 'off';


            % command for running .py file
            % [distance_i,avg_v_i] = calcDistance(app,resultpath);
            % dist       = replace(num2str(distance_i*1e-3),'.',',');
            % vel        = replace(num2str(avg_v_i),'.',',');
            % llen       = replace(num2str(app.LichaamslengteEditField.Value),'.',',');
            % mass       = replace(num2str(app.MassaEditFieldEigenModel.Value),'.',',');
            % shoulders  = replace(num2str(app.AfstandtussenschoudersEditField.Value),'.',',');
            % arm_upper  = replace(num2str(app.AfstandschoudertotelleboogEditField.Value),'.',',');
            % arm_lower  = replace(num2str(app.AfstandelleboogtotvingertopEditField.Value),'.',',');
            % leg_upper  = replace(num2str(app.AfstandheuptotknieEditField.Value),'.',',');
            % leg_lower  = replace(num2str(app.AfstandknietotgrondEditField.Value),'.',',');
            % foot       = replace(num2str(app.LengtevanvoetEditField.Value),'.',',');
            % strength   = replace(num2str(app.SpierkrachtEditField.Value),'.',',');
            % cmd = ['autoFill.py ' app.GroupName ' ' app.ModelName ...
            %     ' ' dist ' ' vel ' ' llen ' ' mass ' ' shoulders ' ' arm_upper...
            %     ' ' arm_lower ' ' leg_upper ' ' leg_lower ' ' foot ' ' strength];
            % pyrunfile(cmd);

            % add result to table
            loadResultsTable(app)

        end

        % Value changed function: GroepEditField
        function GroepEditFieldValueChanged(app, event)
            if isempty(app.GroepEditField.Value)
                return
            end
            % avoid issues with paths
            GroupName_tmp = app.GroepEditField.Value;
            GroupName_tmp(strfind(GroupName_tmp,' ')) = '_';
            GroupName_tmp(strfind(GroupName_tmp,'/')) = '_';
            GroupName_tmp(strfind(GroupName_tmp,'\')) = '_';
            
            while strcmp(GroupName_tmp(1),'_') && length(GroupName_tmp)>1
                GroupName_tmp = GroupName_tmp(2:end);
            end
            
            while strcmp(GroupName_tmp(end),'_') && length(GroupName_tmp)>1
                GroupName_tmp = GroupName_tmp(1:end-1);
            end
            app.GroupName = GroupName_tmp;

            % load table with results for this group
            loadResultsTable(app);
            
        end

        % Callback function
        function NaamEditFieldValueChanged(app, event)
            if isempty(app.NaamModelEditField.Value)
                return
            end
            ModelName_tmp = app.NaamModelEditField.Value;
            ModelName_tmp(strfind(ModelName_tmp,' ')) = '_';
            ModelName_tmp(strfind(ModelName_tmp,'/')) = '_';
            ModelName_tmp(strfind(ModelName_tmp,'\')) = '_';
            
            while strcmp(ModelName_tmp(1),'_') && length(ModelName_tmp)>1
                ModelName_tmp = ModelName_tmp(2:end);
            end
            
            while strcmp(ModelName_tmp(end),'_') && length(ModelName_tmp)>1
                ModelName_tmp = ModelName_tmp(1:end-1);
            end
            app.ModelName = ModelName_tmp;

        end

        % Button pushed function: SpeelvideoButton
        function SpeelvideoButtonPushed(app, event)

            flag = 0;
            if ~exist(app.sel_osim_file,'file')
                flag = 1;
                disp(['Could not find "' app.sel_osim_file '"'])
            end
            if ~exist(app.sel_mot_file,'file')
                flag = 1;
                disp(['Could not find "' app.sel_mot_file '"'])
            end
            if flag
                app.sel_osim_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.osim');
                app.sel_mot_file = fullfile(app.path_repo,'Subjects','Vitruvian_Man','Vitruvian_Man.mot');
            end

            % function
            try
                playVideo(app.sel_osim_file,app.sel_mot_file,app.path_geom);
            catch
            end

            % make close button active
            app.SpeelvideoButton.Enable = 'off';
            app.SpeelvideoButton.Visible = 'off';

            app.SluitvideoButton.Enable = 'on';
            app.SluitvideoButton.Visible = 'on';

        end

        % Button pushed function: SluitvideoButton
        function SluitvideoButtonPushed(app, event)
            try
                [~,~] = system('taskkill /IM simbody-visualizer.exe');
            catch
            end

            app.SluitvideoButton.Enable = 'off';
            app.SluitvideoButton.Visible = 'off';

            app.SpeelvideoButton.Enable = 'on';
            app.SpeelvideoButton.Visible = 'on';

        end

        % Callback function
        function NaamAfstandSnelheidListBoxValueChanged(app, event)
            app.sel_mot_file = app.NaamAfstandSnelheidListBox.Value;

            [~,name_tmp,~] = fileparts(app.sel_mot_file);
            name_tmp2 = getName(app,name_tmp);
            osim_tmp = fullfile(app.path_repo,'Subjects',name_tmp2,[name_tmp2 '.osim']);
            if exist(osim_tmp,'file')
                app.sel_osim_file = osim_tmp;
            else
                res_tmp = app.sel_mot_file;
                res_tmp(end-1) = 'a'; % .mot -> .mat
                load (res_tmp,'model_info');
                app.sel_osim_file = model_info.osim_path;
            end
            

        end

        % Callback function
        function KrachtSliderValueChanged(app, event)
            v = app.KrachtSlider.Value;
            if v < app.KrachtSlider.Limits(2)
                app.usr_speed = round(app.KrachtSlider.Value)/3.6;
            else
                app.usr_speed = -1;
            end
            
        end

        % Callback function
        function LichaamslengteEditFieldValueChanged(app, event)
            readUserInput(app)
            updateUserInput(app)
            writeDefaultUserInput(app)
            updateInputLimits(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ['MATLAB' newline 'App'];
            app.LengteMenu = uimenu(app.UIFigure);
            app.LengteMenu.Text = ['Wat' newline 'gebeurt' newline 'er' newline 'als' newline 'je' newline 'heel' newline 'klein' newline 'bent' newline 'of' newline 'juist' newline 'heel' newline 'groot?'];
            app.KrachtMenu = uimenu(app.UIFigure);
            app.KrachtMenu.Text = ['Wat' newline 'als' newline 'je' newline 'spieren' newline 'extra' newline 'sterk' newline 'of' newline 'juist' newline 'heel' newline 'zwak' newline 'zijn?'];
            app.ZwaartekrachtMenu = uimenu(app.UIFigure);
            app.ZwaartekrachtMenu.Text = ['Hoe' newline 'beweeg' newline 'je' newline 'als' newline 'de' newline 'zwaartekracht' newline 'anders' newline 'is,' newline 'zoals' newline 'op' newline 'de' newline 'maan' newline 'of' newline 'op' newline 'een' newline 'andere' newline 'planeet?'];
            app.Creer_jezelf_als_modelMenu = uimenu(app.UIFigure);
            app.Creer_jezelf_als_modelMenu.Text = 'Creëer_jezelf_als_model!';
            app.Background_creerjeeigenmodel = uiimage(app.UIFigure);
            app.Background_creerjeeigenmodel.ImageSource = fullfile(pathToMLAPP, 'createyourownmodel_v2.png');
            app.spaceBackground = uiimage(app.UIFigure);
            app.spaceBackground.ImageSource = fullfile(pathToMLAPP, 'Space_drawing.png');
            app.BackgroundImageSmurfReusKracht = uiimage(app.UIFigure);
            app.BackgroundImageSmurfReusKracht.ImageSource = fullfile(pathToMLAPP, 'Background_landscape_path.png');
            app.GroepEditFieldLabel = uilabel(app.UIFigure);
            app.GroepEditFieldLabel.Text = 'Groep';
            app.OpstapmetsuperkrachtBlue = uilabel(app.UIFigure);
            app.OpstapmetsuperkrachtBlue.Text = ['Op' newline 'stap' newline 'met' newline 'superkracht!'];
            app.PlaneetX = uiimage(app.UIFigure);
            app.PlaneetX.ImageSource = fullfile(pathToMLAPP, 'PlanetX_purple.png');
            app.Spaceman = uiimage(app.UIFigure);
            app.Spaceman.ImageSource = fullfile(pathToMLAPP, 'Spaceman_nobackground.png');
            app.SpierkrachtEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpierkrachtEditField.Limits = [20 500];
            app.SpierkrachtEditField.ValueDisplayFormat = '%111g';
            app.SpierkrachtEditField.Value = 100;
            app.procentSpierkracht = uilabel(app.UIFigure);
            app.procentSpierkracht.Text = '%';
            app.LengteSliderReusSmurf = uislider(app.UIFigure, 'range');
            app.LengteSliderReusSmurf.MajorTicks = [0 50 100];
            app.LengteSliderReusSmurf.MajorTickLabels = {'Smurf', 'Mens', 'Reus'};
            app.LengteSliderReusSmurf.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.LengteSliderReusSmurf.Step = 50;
            app.PlaneetXLabel = uilabel(app.UIFigure);
            app.PlaneetXLabel.Text = ['Planeet' newline 'X'];
            app.gLabel = uilabel(app.UIFigure);
            app.gLabel.Text = 'g';
            app.MaanLabel = uilabel(app.UIFigure);
            app.MaanLabel.Text = 'Maan';
            app.ZwaartekrachtEditFieldLabelMaan = uilabel(app.UIFigure);
            app.ZwaartekrachtEditFieldLabelMaan.Text = 'Zwaartekracht';
            app.gLabel_3 = uilabel(app.UIFigure);
            app.gLabel_3.Text = 'g';
            app.ZwaartekrachtEditFieldLabelPlanetX = uilabel(app.UIFigure);
            app.ZwaartekrachtEditFieldLabelPlanetX.Text = 'Zwaartekracht';
            app.ZwaartekrachtEditFieldPlanetX = uieditfield(app.UIFigure, 'numeric');
            app.ZwaartekrachtEditFieldPlanetX.Limits = [0.165 9];
            app.ZwaartekrachtEditFieldPlanetX.ValueDisplayFormat = '%111g';
            app.ZwaartekrachtEditFieldPlanetX.Value = 1;
            app.ZwaartekrachtEditFieldMaan = uieditfield(app.UIFigure, 'numeric');
            app.ZwaartekrachtEditFieldMaan.Limits = [0.165 0.165];
            app.ZwaartekrachtEditFieldMaan.ValueDisplayFormat = '%111g';
            app.ZwaartekrachtEditFieldMaan.Value = 0.165;
            app.StrongWoman = uiimage(app.UIFigure);
            app.StrongWoman.ImageSource = fullfile(pathToMLAPP, 'StrongWoman_nobackground.png');
            app.giant = uiimage(app.UIFigure);
            app.giant.ImageSource = fullfile(pathToMLAPP, 'Giant_nobackground.png');
            app.smurf = uiimage(app.UIFigure);
            app.smurf.ImageSource = fullfile(pathToMLAPP, 'Smurf_nobackground.png');
            app.DENKreus = uiimage(app.UIFigure);
            app.DENKreus.Enable = 'off';
            app.DENKreus.Visible = 'off';
            app.DENKreus.ImageSource = 'balloon.png';
            app.GIFreus = uiimage(app.UIFigure);
            app.GIFreus.Enable = 'off';
            app.GIFreus.Visible = 'off';
            app.GIFreus.ImageSource = 'allgifs.gif';
            app.DENKsmurf = uiimage(app.UIFigure);
            app.DENKsmurf.Enable = 'off';
            app.DENKsmurf.Visible = 'off';
            app.DENKsmurf.ImageSource = 'balloon.png';
            app.GIFsmurf = uiimage(app.UIFigure);
            app.GIFsmurf.Enable = 'off';
            app.GIFsmurf.Visible = 'off';
            app.GIFsmurf.ImageSource = 'allgifs.gif';
            app.maan = uiimage(app.UIFigure);
            app.maan.ImageSource = fullfile(pathToMLAPP, 'moon_drawing.png');
            app.procentlabelEigenModel = uilabel(app.UIFigure);
            app.procentlabelEigenModel.Text = '%';
            app.SpierkrachtEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.SpierkrachtEigenModel.Limits = [20 500];
            app.SpierkrachtEigenModel.ValueDisplayFormat = '%111g';
            app.SpierkrachtEigenModel.Value = 100;
            app.kgLabelEigenModel = uilabel(app.UIFigure);
            app.kgLabelEigenModel.Text = 'kg';
            app.MassaEditFieldLabel = uilabel(app.UIFigure);
            app.MassaEditFieldLabel.Text = 'Massa';
            app.MassaEditFieldEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.MassaEditFieldEigenModel.Limits = [20 300];
            app.MassaEditFieldEigenModel.ValueDisplayFormat = '%111g';
            app.MassaEditFieldEigenModel.Value = 75;
            app.GroepEditField = uieditfield(app.UIFigure, 'text');
            app.GroepEditField.ValueChangedFcn = createCallbackFcn(app, @GroepEditFieldValueChanged, true);
            app.GroepEditField.Placeholder = ['(Geef' newline 'de' newline 'naam' newline 'van' newline 'jouw' newline 'groepje)'];
            app.StartsimulatieButton = uibutton(app.UIFigure, 'push');
            app.StartsimulatieButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulatieButtonPushed, true);
            app.StartsimulatieButton.Text = ['Start' newline 'simulatie'];
            app.SpeelvideoButton = uibutton(app.UIFigure, 'push');
            app.SpeelvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SpeelvideoButtonPushed, true);
            app.SpeelvideoButton.Text = ['Speel' newline 'video'];
            app.SluitvideoButton = uibutton(app.UIFigure, 'push');
            app.SluitvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SluitvideoButtonPushed, true);
            app.SluitvideoButton.Enable = 'off';
            app.SluitvideoButton.Visible = 'off';
            app.SluitvideoButton.Text = ['Sluit' newline 'video'];
            app.NaamModelEditField = uieditfield(app.UIFigure, 'text');
            app.NaamModelEditField.Placeholder = ['(Geef' newline 'de' newline 'naam' newline 'van' newline 'jouw' newline 'groepje)'];
            app.NaamModelEditField.Value = ['(Geef' newline 'de' newline 'naam' newline 'van' newline 'het' newline 'model)'];
            app.PlanetXblack = uiimage(app.UIFigure);
            app.PlanetXblack.ImageSource = fullfile(pathToMLAPP, 'PlanetX_dark.png');
            app.ZwaartekrachtEigenModel = uieditfield(app.UIFigure, 'numeric');
            app.ZwaartekrachtEigenModel.Limits = [0.1 5];
            app.ZwaartekrachtEigenModel.ValueDisplayFormat = '%111g';
            app.ZwaartekrachtEigenModel.Value = 1;
            app.gLabelEigenModel = uilabel(app.UIFigure);
            app.gLabelEigenModel.Text = 'g';
            app.OpstapmetsuperkrachtWhite = uilabel(app.UIFigure);
            app.OpstapmetsuperkrachtWhite.Text = ['Op' newline 'stap' newline 'met' newline 'superkracht!'];
            app.LengteSliderEigenModel = uislider(app.UIFigure, 'range');
            app.LengteSliderEigenModel.Limits = [80 180];
            app.LengteSliderEigenModel.MajorTicks = [80 100 120 140 160 180];
            app.LengteSliderEigenModel.MajorTickLabels = {'80', '100', '120', '140', '160', '180'};
            app.LengteSliderEigenModel.MinorTicks = [80 85 90.95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175180];
            app.LengteSliderEigenModel.Step = 1;
            app.LengteSliderEigenModel.Value = [80 180];
            app.LengteLabelEigenModel = uilabel(app.UIFigure);
            app.LengteLabelEigenModel.Text = 'Lengte';
            app.cmLabelEigenModel = uilabel(app.UIFigure);
            app.cmLabelEigenModel.Text = 'cm';
            app.SpierkrachtLabelEigenModel = uilabel(app.UIFigure);
            app.SpierkrachtLabelEigenModel.Text = 'Spierkracht';
            app.ZwaartekrachtLabelEigenModel = uilabel(app.UIFigure);
            app.ZwaartekrachtLabelEigenModel.Text = 'Zwaartekracht';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'balloon_reflected.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'allgifs.gif';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.Enable = 'off';
            app.Image_4.Visible = 'off';
            app.Image_4.ImageSource = 'balloon.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.Enable = 'off';
            app.Image2_4.Visible = 'off';
            app.Image2_4.ImageSource = 'allgifs.gif';
            app.SpierkrachtLabelStrongWoman = uilabel(app.UIFigure);
            app.SpierkrachtLabelStrongWoman.Text = 'Spierkracht';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpStapMetSuperKracht2025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end